classdef gui_exe2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Buttons                     matlab.ui.container.Panel
        RemoveFrameorTissueButton   matlab.ui.control.Button
        LoadPreviousAnalysisButton  matlab.ui.control.Button
        AnalyzeMotilityButton       matlab.ui.control.Button
        LoadTissueAnalyzePermeabilityButton  matlab.ui.control.Button
        SaveAnalysisButton          matlab.ui.control.Button
        Plots                       matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        UIAxes_current              matlab.ui.control.UIAxes
        UIAxes_all                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        exp_counter = 0 % Number of tissues loaded for analysis
        data = {} % Cell array of analysis data for the excel file
        raw_data = {} % Cell array of the raw data before normalization
        peri = {} % Array of perimeter for each tissue
        selectedPath % Path for the tissue
        tissue_loaded = false % a flag that shows if a tissue was loaded or not
        exp_cropped = false % a flag that if true it allows to analyze peristalsis
        intervals = [] % a variable that states the intervals between frames of the tissue analyzed
        results_folder_created = false % a flag that shows if a folder to save the results have been created or not
        results_dir % the results folder directory
        exp_names % a cell array that will hold the name given by the user to each tissue
        min_frames % a variable that states the minimum number of frames of all the tissues analyzed
        % the variable above is used to make sure all tissues have the
        % same amount of frames when the data is exported or used for
        % statistical analysis, which will be done by using
        % data(1:app.min_frames).
    end
    
    methods (Access = private)
        
        % a function the checks and creates the results folder in which all
        % the analysis results will be saved.
        function createResultsFolder(app)
            if app.results_folder_created == false
                current_folder = pwd;
                uiwait(msgbox('Please choose where would you like the results folder to be saved'));
                folder_location = uigetdir('.', 'Results folder location');
                % go to the original folder
                cd(folder_location);
                % create the name of the new folder
                % datestr with the input values returns the date in a
                % yyyy-mm-dd format that is better for sorting data.
                folder = [datestr(today, 29) ' Analysis Results'];                
                % check if the folder exists. if it doesn't, create it
                if ~exist(folder, 'dir')
                    mkdir(folder)
                else
                    % to allow multiple analyses, if the folder exists, we
                    % enter a loop and check if the "folder name (i)" 
                    % exists, untill we find an i where the folder doesn't
                    % exist. then we make that folder.
                    i = 2;
                    while true
                        check_name = [folder ' (' num2str(i) ')'];
                        if ~exist(check_name, 'dir')
                            folder = check_name;
                            mkdir(folder)
                            break;
                        end
                        i = i + 1;
                    end
                end
                % save the directory of the results folder
                app.results_dir = [pwd '\' folder '\'];
                app.results_folder_created = true;
                cd(current_folder);
            end
        end

        % this function is used when loading previous analyses. it copies
        % the segmentation images and gut animation videos to the new
        % results folder for the current analysis
        function copyFilesFromLoadedAnalysis(app, filePath)
            % this command copies all images and videos to the new results
            % folder.
            copyfile([filePath '\*.png'], app.results_dir);
            copyfile([filePath '\*.avi'], app.results_dir);
        end

        function updateTextBox(app)
            % concatenating the guts numbers and their corresponding names, 
            % and eventually updating the text box with the names.
            expanded_legend = ['Gut #1 = ' app.exp_names{1}];
            for i=2:app.exp_counter
                expanded_legend = [expanded_legend ', Gut #' num2str(i) ' = ' app.exp_names{i}];
            end
            app.TextArea.Value = expanded_legend;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadPreviousAnalysisButton
        function LoadPreviousAnalysis(app, event)
            createResultsFolder(app);
            if app.exp_counter == 0
                uiwait(msgbox('Please choose the excel file to load'));
            end
            % ask the user for the excel file to load
            [fileName, filePath] = uigetfile('*.xls*');
            
            copyFilesFromLoadedAnalysis(app, filePath);
            
            % read excel file into a cell matrix
            loaded_data = readcell(strcat(filePath, fileName), 'Sheet', 'normalized data');
            loaded_raw_data = readcell(strcat(filePath, fileName), 'Sheet', 'raw data');
            % get the first row of the excel file which is the tissues
            % names, and add it to app.exp_names
            loaded_exp_names = loaded_data(1, :);           
            app.exp_names = [app.exp_names loaded_exp_names];
            % get the second row of the excel file which contains the
            % tissues intervals, which is a cell array where every
            % element is a string in the format: 'intervals - x min'.
            temp = loaded_data(2, :);
            % next cellfun is used to split every string with the delimeter
            % ' ', so that we can extract only the number from the string
            % above.
            temp = cellfun(@(x) strsplit(x, ' '), temp, 'UniformOutput', false);
            temp = vertcat(temp{:});
            % now we take only the third column - which is only the number
            % in a string format
            loaded_exp_intervals = temp(:, 3);
            % now we turn the cells into a normal arr with cell2mat, and on
            % that we call str2num to turn the types to integers. we
            % transpose with ' to make it a row array instead of column.
            loaded_exp_intervals = str2num(cell2mat(loaded_exp_intervals))';
            % we add the intervals to app.intervals.
            app.intervals = [app.intervals loaded_exp_intervals];
            % a loop that runs for the amount of tissues that are
            % present in the previous analysis that is loaded.
            for i=1:size(loaded_data, 2) % 1 if exp are in rows, 2 if exp are in columns
                % if the exp loaded is the first tissue in this
                % program's run, then assign to min_frames the amount of
                % frames in the exp.
                if app.exp_counter == 0
                    app.min_frames = length(loaded_data(3:end, i));
                else
                    % otherwise, check if the loaded tissues have a 
                    % lower number of frames, and if yes - update min_frames
                    if length(loaded_data(3:end, i)) < app.min_frames
                        app.min_frames = length(loaded_data(3:end, i));
                    end
                end
                % raise the amount of tissue counter by 1
                app.exp_counter = app.exp_counter + 1;                
                % save the data from the previous analysis
                app.data{app.exp_counter} = cell2mat(loaded_data(3:end, i)');
                % save the raw data of the i'th tissue in a tmp variable
                exp_data = loaded_raw_data(i, 3:end);
                % next line is to remove all of the NaN values from the
                % cell array
                exp_data(cellfun(@(x) any(ismissing(x)), exp_data)) = [];
                % add the clean raw data to app.raw_data
                app.raw_data{app.exp_counter} = cell2mat(exp_data);
                % since we don't have the images of the previous analysis,
                % we also don't have the perimeter information of those,
                % which is needed to save the image montage of the
                % segmentation done on those tissues. therefore, we
                % assign the value -1 to differentiate between the
                % tissues done now, and the tissues loaded from a
                % previous analysis
                app.peri{app.exp_counter} = -1;
                
                % add the data to the plot of all tissues. we use
                % cell2mat because the data is in a cell array, and plot
                % doesn't work with this data structure.
                exp_data = cell2mat(loaded_data(3:end, i)');
                interval = app.intervals(app.exp_counter);
                % create the x values of the data for every picture in the
                % tissue, so that it is affected by the interval
                x = interval:interval:length(exp_data)*interval;
                plot(app.UIAxes_all, x, exp_data, '-x');
                hold(app.UIAxes_all, 'on'); % to allow adding more plots to all tissues plot
            end
            % update the legend
            for i=1:app.exp_counter
                lgnd{i} = "Gut #" + num2str(i);
            end
            legend(app.UIAxes_all, lgnd, 'Location', 'northwest', 'NumColumns', 2);
            % clear the current axis
            cla(app.UIAxes_current);
            % update the text box with the expanded legend
            updateTextBox(app)
            % notify the user that the analysis has been succesfully loaded
            msgbox("Previous analysis loaded succesfully!");
        end

        % Button pushed function: LoadTissueAnalyzePermeabilityButton
        function LoadExpAndAnalyze(app, event)
            %% --- create the results folder ---
            createResultsFolder(app);
            %% --- read images into a data-structre  ---
            % initialize fileNames to be able to enter loop
            fileNames = {};
            % flag to print a different prompt when an invalid input is
            % entered
            flag = false;
            % the formats of images
            formats = ["*.jpg", "*.bmp", "*.tif"];
            % initialize an empty structure for dirOutput
            dirOutput = struct([]);
            % function to merge structs, to be able to merge structs
            % containing jpg images with structs with bmp images etc.
            mergestructs = @(x,y) cell2struct([struct2cell(x),struct2cell(y)],fieldnames(y),1);
            while isempty(fileNames)
                % if after the first folder chosen the program didn't find
                % any images, it presents a ui saying that
                if flag == true
                    prompt = "Didn't find any pictures of format " + ...
                        "jpg/bpm/tif in the folder chosen, please try again";
                    fig = uifigure;
                    selection = uiconfirm(fig, prompt, "Warning");
                    close(fig);
                    if selection == "Cancel"
                        break
                    end
                end
                if app.exp_counter == 0
                    uiwait(msgbox('Please choose the tissue file to load'));
                end
                % ask the pictures path from the user, save it into "selectedPath" variable
                app.selectedPath = uigetdir('.');
                % take the pictures from selectedPath, save it into "dirOutput" struct
                cd(app.selectedPath);
                % go over the possible image formats
                for i = 1:length(formats)
                    dirTemp = dir(formats(i));
                    % if images were found
                    if size(dirTemp) > 0
                        % check if any images were not found beforehand
                        if size(dirOutput) == 0
                            dirOutput = dirTemp;
                            continue
                        end
                        %if images were found before, merge the images
                        dirOutput = mergestructs(dirOutput, dirTemp);
                    end
                end
                % extract data for the variables above, from the fields in "dirOutput" struct
                if size(dirOutput) > 0
                    fileNames = {dirOutput.name}';
                end
                flag = true;
            end
            numFrames = numel(fileNames);
            % checks if it is the first tissue analyzed, if yes assign
            % the value of numFrames to min_frames.
            if app.exp_counter == 0
                app.min_frames = numFrames;
            else
                % check if numFrames is less than min_frames, if yes change
                % it.
                if numFrames < app.min_frames
                    app.min_frames = numFrames;
                end
            end
            % read the data of the first picture into the variable "Pic1"
            Pic1 = imread(fileNames{1});
            % preallocate array - create zeros 4-D uint8 variable, with the size of Pic1 * numFrames
            imageSeries = zeros([size(Pic1) numFrames],class(Pic1));
            % fill the first numerical pixels values of Pic1
            imageSeries(:,:,:,1) = Pic1;
            % fill the rest numerical pixels values into the imageSeries struct
            for p = 2:numFrames
                imageSeries(:,:,:,p) = imread(fileNames{p});
            end
            app.tissue_loaded = true;
            app.exp_cropped = false;
            
            % ask the user for the name and date of the tissue
            inputs = '';
            name = '';
            date = '';
            gut_num = '';
            interval = '';
            prompts = {'Tissue name:' 'Date of the experiment:' 'Number of gut that is analyzed:' 'Interval between frames in this experiment (in minutes):'};
            while isempty(inputs) || isempty(name) || isempty(date) || isempty(gut_num)
                inputs = inputdlg(prompts);
                % extract the inputs into their designated variables
                name = inputs{1};
                date = inputs{2};
                gut_num = inputs{3};
                interval = inputs{4};
            end
            
            % concatenate the inputs given
            exp_name = [name ' ' date ' gut ' gut_num];
            
            % add the name given by the user to exp_names. we use
            % exp_counter+1 because we only raise it's value at the end of
            % this callback function. also add the intervals of this exp.
            app.exp_names{app.exp_counter+1} = exp_name;
            interval = str2num(interval);
            app.intervals(app.exp_counter+1) = interval;
            %% --- cropping ---
            % crop the images menually
            img = imageSeries(:,:,:,1); % keep the first image
            flag = false;
            % initialize a "flag" for the drawing loop. if it's 1=>draw again, if it's 0=>crop
            draw = 1;
            % drawing loop
            while draw == 1
                % initialize new data-structure for the images after cropping
                if flag == true
                    clear croppedImageSeries; % clean previous versions of cropped images
                end
                flag = true;
                figure(1);
                imshow(img);
                uiwait(msgbox('Draw a rectengle to crop the image'));
                %h = msgbox('Draw a rectengle to crop the image'); % open another visual box with intructions
                %pause(2) %in seconds
                % let the user create a rectangle on the screen, save the rectangle positions (coordinates) in r1
                r1 = drawrectangle('Label','','Color',[1 0 0]);
                for k = 1:numFrames
                    croppedImageSeries(:,:,:,k) = imcrop(imageSeries(:,:,:,k), r1.Position);
                end
                % display the cropped images to the user
                figure(1)
                title('Cropped images')
                %delete(h);
                montage(croppedImageSeries) % Display multiple image frames as rectangular montage
                % ask for user feedback
                selection = questdlg('Would you like to continue?', ...
            	    'Confirm cropped image series', ...
            	    'Confirm','Crop again','Crop again');
                % if the approve, stop the loop. else, do it all over again
                if strcmp(selection,'Confirm')
                    draw = 0;
                end
            end            
            %% --- preprocessing ---
            % Short explanation for the software developer:
            % Write in the Matlab command line : size(croppedImageSeries). you should
            % get 4 numbers. for example: 154   277     3    24. it means:
            % height   width   RGB*   number of images in the structe.
            % RGB = red, green, blue. for each image, each pixel have those 3 colors, expressed by numerical value.
            % in the croppedImageSeries case, the value in index 1 = red values, index 2 = green values, index 3 = blue values. 
            
            for k = 1:numFrames
            % split channels - green channel (index 2 of the third dimension - as i explained above)
            % greenChannelSeries keeps only the green values of the pictures
            greenChannelSeries(:,:,k) = croppedImageSeries(:,:,2,k);
            
            % Gaussian filter - Smooth Image. 2 = std
            guass(:,:,k) = imgaussfilt(greenChannelSeries(:,:,k),2);
            
            % find threshold
            OtsuLevel(:,k) = graythresh(guass(:,:,k));
            % apply threshold - "bw" conatins pixel values of "1" to the dark areas, "0" to the green areas
            bw(:,:,k) = imcomplement(imbinarize(guass(:,:,k), OtsuLevel(:,k)));
            
            % Enframe the gut (the unchecked area), 8 pixels away
            radius = 8;
            decomposition = 0;
            % strel command create Morphological structuring element
            se = strel('disk', radius, decomposition);
            % bw data-structure now conatins the "1" pixels minus 8 pixels
            bw(:,:,k) = imerode(bw(:,:,k), se);
            end
            
            %% --- mask the inverted image with the thresholded image ---
            for k = 1:numFrames
                %medium mask
                Medium_Bw_uint8(:,:,k) = uint8(bw(:,:,k)); % convert logical to integer
                % bring the "1" pixels ( - 8 eight pixels) their real numeric value, keep the 0 pixels as 0
                Medium_Masked(:,:,k) = greenChannelSeries(:,:,k) .* Medium_Bw_uint8(:,:,k);
            end
            
            %% --- measure grayscale values ---
            for k = 1:numFrames
                %medium measurements
                MediumStats (k)= regionprops(Medium_Bw_uint8(:,:,k), Medium_Masked(:,:,k), 'MeanIntensity'); %mean intensity
                MediumMI(k) = MediumStats(k).MeanIntensity; % MediumMI values are used in the main script
            end
            
            % normalized to first 3 average
            %first3_avg = mean(MediumMI(1:3)) % first 3 frames average
            %normal_MediumMI = MediumMI./first3_avg;
            
            % normalized to first frame
            normal_MediumMI = MediumMI./MediumMI(1);
            %% --- find perimeter ---
            for k = 1:numFrames 
                findper(:,:,k) = bwperim(bw(:,:,k)); % perimeter
                per(:,:,k) = greenChannelSeries(:,:,k) + 255 * uint8(findper(:,:,k)); % convert,scale, and overlay perimeter on image
            end
            ImName = exp_name + " segmentation.png";
            ImFileOut = fullfile(app.results_dir, ImName);
            f = figure('Name', 'Segmentation');
            h = montage(per);
            montage_IM = h.CData;
            imwrite(montage_IM, ImFileOut);
            %% create video
            % create the video in the results folder with the name of guy
            % animation and the number of the tissue. i used
            % exp_counter+1 because i raise exp_counter at the end of this
            % function
            video_name = strcat(app.results_dir, app.exp_names{app.exp_counter+1} ,' animation.avi');
            % open video recorder with the name above
            writer_obj = VideoWriter(video_name);
            writer_obj.FrameRate = 5;
            open(writer_obj);
            
            % normalize to average backgorund of ctrl pic1
            original_normal_factor = 8.6612;
            
            % define color map - Jet
            cmap = jet(256);
            % create figure
            figure(30)
            axis equal
            axis off
            %hold on
            for k = 1:numFrames
                if (k==1)
                    normal_factor = mean(mean(Medium_Masked(:,:,1))); % calculate average of the first frame
                    divideBy = original_normal_factor/normal_factor; % create normalization factor
                end
                colourArray = ind2rgb(double(Medium_Masked(:,:,k)*divideBy*3)+1, cmap); % draw the picture again with cmap*3 sensitivity
                image(colourArray) % display the drawing
                writeVideo(writer_obj, getframe); % record current drawing
                pause(0.1)
            end
            pause(1);
            close(writer_obj); % close recording
            close(figure(30));
            close(figure(1));
            close(f);
            %% Saving analysis results &&
            app.exp_counter = app.exp_counter + 1;
            app.raw_data{app.exp_counter} = MediumMI;
            app.data{app.exp_counter} = normal_MediumMI; %save medium stats
            app.peri{app.exp_counter} = per; %save perimeter
            app.tissue_loaded = false; % update that we finished working on current tissue
            app.exp_cropped = true; % update variable to allow peristalsis analysis
            
            % create the x values of the data for every picture in the
            % tissue, so that it is affected by the interval
            x = interval:interval:length(normal_MediumMI)*interval;
            % show plots
            plot(app.UIAxes_all, x, normal_MediumMI, '-x');
            hold(app.UIAxes_all, 'on'); % to allow adding more plots to all tissues plot
            % next loop is to create the legend for all tissues
            for i=1:app.exp_counter
                lgnd{i} = "Gut #" + num2str(i);
            end
            legend(app.UIAxes_all, lgnd, 'Location', 'northwest', 'NumColumns', 2);
            plot(app.UIAxes_current, x, normal_MediumMI, '-x');
            % update the text box with the expanded legend
            updateTextBox(app)
            % let the user know the analysis is done
            msgbox("Analysis done!")
        end

        % Button pushed function: RemoveFrameorTissueButton
        function RemoveFrameOrExp(app, event)
            if app.exp_counter == 0
                warndlg('No tissues analyzed yet!','Warning');
                return;
            end

            % Ask the user to choose what he wants to remove
            answer = questdlg('Would you like to remove a frame or a whole tissue analysis?', ...
                'Choose an option', 'Frame', 'Tissue', 'Cancel');
            switch answer
                case 'Frame'
                    % let the user decide which frame in which tissue
                    % to remove
                    prompts = {'In which tissue do you want to remove a frame?' 'Which frame do you want to remove?'};
                    inputs = inputdlg(prompts);
                    % extract the inputs and convert to num
                    gut = str2num(inputs{1});
                    frame = str2num(inputs{2});
                    % check if the gut input by the user is valid
                    if (gut <= 0) || (gut > app.exp_counter)
                        warndlg('Tissue number entered is not valid!','Warning');
                        return;
                    end
                    frame = frame / app.intervals(gut);
                    if frame == 1
                        % check if the frame entered is the first frame of
                        % the tissue
                        warndlg('Cannot remove first frame!','Warning');
                        return;
                    elseif floor(frame) ~= frame
                        % when we divide frame by the interval of that
                        % tissue, we expect to get an integer. if the
                        % number we recieved is an integer, then
                        % floor(frame) == frame. if it's not an int, the
                        % user entered a wrong frame number, so we need to
                        % display a warndlg and return.
                        warndlg('Invalid frame entered!','Warning');
                        return;
                    elseif frame == length(app.data{gut})
                        % check if the frame entered is the last frame of
                        % the tissue. if yes - remove it.
                        app.data{gut}(frame) = [];
                        % remove it's peri value only of peri != -1. this
                        % way, if we remove a frame from a tissue we
                        % loaded from a previous analysis, it doesn't
                        % change the peri value.
                        if app.peri{gut} ~= -1, app.peri{gut}(frame) = []; end 
                        if length(app.data{gut}) < app.min_frames
                            % check if after removing the frame there is a
                            % new min_frames - if so update it.
                            app.min_frames = length(app.data{gut});
                        end
                    elseif frame <= 0 || frame > length(app.data{gut})
                        % check if frame entered is invalid
                        warndlg('Frame number entered is not valid!','Warning');
                        return;
                    else
                        % else frame entered is valid, so calculate the
                        % average of the frame before and after the
                        % desired frame to remove.
                        avg_of_adjacent_frames = (app.data{gut}(frame-1) + app.data{gut}(frame+1))/2;
                        % assign the average calculated to the removed framed
                        app.data{gut}(frame) = avg_of_adjacent_frames;
                    end
                    % check if the frame removed is in the last tissue
                    % analyzed, and if it is not an tissue we loaded
                    % from previous analysis. if yes - we want to update
                    % the "current tissue" axes, which is what we do
                    % inside the if condition.
                    if gut == app.exp_counter
                        if app.peri{gut} ~= -1
                            interval = app.intervals(gut);
                            x = interval:interval:length(app.data{gut})*interval;
                            plot(app.UIAxes_current, x, app.data{gut}, '-x');
                        end
                    end
                    % clear the "all tissues" axes, so we can plot the
                    % updated data on it

                    cla(app.UIAxes_all);
                    hold(app.UIAxes_all, 'on'); % to allow adding more plots to all tissues plot
                    for i=1:app.exp_counter
                        interval = app.intervals(i);
                        x = interval:interval:length(app.data{i})*interval;
                        plot(app.UIAxes_all, x, app.data{i}, '-x');
                        % update the legend
                        lgnd{i} = "Gut #" + num2str(i);
                    end
                    legend(app.UIAxes_all, lgnd, 'Location', 'northwest', 'NumColumns', 2);

                case 'Tissue'
                    % ask the user which tissue to remove
                    prompts = {'Which tissue do you want to remove?'};
                    inputs = inputdlg(prompts);
                    % extract the inputs and convert to num
                    gut = str2num(inputs{1});
                    % check if the gut num input by the user is valid
                    if (gut <= 0) || (gut > app.exp_counter)
                        warndlg('Tissue number entered is not valid!','Warning');
                        return;
                    end
                    
                    % if we want to delete a tissue of a previously
                    % loaded analysis, then we also need to delete the
                    % segmentation pics and videos of the tissue that
                    % we transfered to the results folder.
                    if app.peri{gut} == -1
                        files_to_delete = [app.results_dir app.exp_names{gut} '*'];
                        delete(files_to_delete);
                    end

                    % this flag will be true if the tissue removed had
                    % the same amount of min_frames. this way, if it's
                    % true, we know we need to adjust the min_frames value
                    % according to the other tissues remaining.
                    flag = app.min_frames == length(app.data(gut));
                    % delete the data, peri and tissue name values of the
                    % removed tissue
                    app.data(gut) = [];
                    app.peri(gut) = [];
                    app.exp_names(gut) = [];
                    % check if the removed tissue is the last one. if
                    % yes - remove it from the "current tissue" axes.
                    if gut == app.exp_counter
                        cla(app.UIAxes_current);
                    end
                    % decrease exp_counter by 1
                    app.exp_counter = app.exp_counter - 1;
                    % clear the "all tissues" axes, so we can plot the
                    % updated data on it
                    cla(app.UIAxes_all);
                    hold(app.UIAxes_all, 'on'); % to allow adding more plots to all tissues plot
                    for i=1:app.exp_counter
                        if flag == true
                            % we use the flag to assign the number of
                            % frames in the first tissue to the
                            % min_frames variable, and afterwards we change
                            % flag to false. this way we only enter this if
                            % condition once in the loop.
                            app.min_frames = length(app.data{i});
                            flag = false;
                        end
                        if length(app.data{i}) < app.min_frames
                            % now we check if the amount of frames in the
                            % i'th tissue is lower than min_frames, and
                            % change it if true.
                            app.min_frames = length(app.data{i});
                        end
                        interval = app.intervals(i);
                        x = interval:interval:length(app.data{i})*interval;
                        plot(app.UIAxes_all, x, app.data{i}, '-x');
                        % update the legend
                        lgnd{i} = "Gut #" + num2str(i);
                    end
                    legend(app.UIAxes_all, lgnd, 'Location', 'northwest', 'NumColumns', 2);
                    % update the text box with the expanded legend
                    updateTextBox(app)
                
                case 'Cancel'
                    return;
            end
        end

        % Button pushed function: AnalyzeMotilityButton
        function AnalyzeMotility(app, event)
            warndlg('This button has no purpose at the moment, sorry :)','Warning');
            return
            if app.exp_cropped == false
                warndlg('You first need to analyze the tissue before analyzing motility.','Warning');
                return;
            end
        end

        % Button pushed function: SaveAnalysisButton
        function SaveAnalysisButtonPushed(app, event)
            if app.exp_counter == 0
                warndlg('No data analysed to save!','Warning');
                return;
            end
            %% --- export to excel ---
            cd(app.results_dir);
            filename = 'data_MFI.xlsx';

            % save normalized data
            for i=1:length(app.data)
                data_array = app.data{i}(1:app.min_frames); % extract the array from data cell array

                % transpose the data so we can save it in columns
                data_array = data_array';

                % convert to cell array, so that it can store both the data
                % which is numbers, and strings which is the tissue name, and
                % also add the tissue name to the beginning of the
                % array
                cell_data = [app.exp_names{i}; ['intervals - ' num2str(app.intervals(i)) ' min']; num2cell(data_array)];
                % when i=1 we create final data matrix that will be saved
                % to the excel file. afterwards, we append the following
                % data to it to form a matrix.
                if i == 1
                    data_matrix = cell_data;
                else
                    data_matrix = [data_matrix cell_data];
                end
            end
            % write the data_matrix into an excel file. we use writecell
            % because it's a matrix of cells
            writecell(data_matrix, filename, 'Sheet', 'normalized data');

            % save raw data
            for i=1:length(app.data)
                raw_data_array = app.raw_data{i};

                % convert to cell array, so that it can store both the data
                % which is numbers, and strings which is the tissue name, and
                % also add the tissue name to the beginning of the
                % array
                cell_raw_data = [app.exp_names{i}, ['intervals - ' num2str(app.intervals(i)) ' min'], num2cell(raw_data_array)];
                % when i=1 we create the excel file and write the first
                % tissue's raw data, and afterwards we just append the next
                % tissues' raw data. this way we allow writing to excel
                % rows with different amount of elements in them.
                if i == 1
                    writecell(cell_raw_data, filename, 'Sheet', 'raw data');
                else
                    writecell(cell_raw_data, filename, 'Sheet', 'raw data', 'WriteMode','append');
                end
            end
            %% --- create txt file with tissue data ---
            % create the empty strings to be able to enter the while loop.
            % this loop is made to force the user to add documentation, and
            % it will exit the loop only when the user entered data for who
            % made the analysis, on which tissues, what type of
            % analysis, and the intervals of the experiment.
            inputs = '';
            name = '';
            tissues = '';
            prompts = {'Analysis done by' 'Which tissues did you analyze' 'Additional details*'};
            while isempty(inputs) || isempty(name) || isempty(tissues)
                inputs = inputdlg(prompts);
                % extract the inputs into their designated variables
                name = inputs{1};
                tissues = inputs{2};
                details = inputs{3};
            end

            fid = fopen('details.txt', 'wt'); % create the txt file
            % writes the next three inputs into the text file, and adds the
            % additional details if any were given
            fprintf(fid, "# Analysis done by: " + name + newline);
            fprintf(fid, "# Tissues analyzed: " + tissues + newline);

            if ~isempty(details), fprintf(fid, "# Additional details: " + details + newline); end
            fclose(fid); % closes file
            %% --- change results folder to a concatenation of the tissues names ---
            % to make the results folder name more informative, this part
            % will concatenate the tissues' names.
            new_name = app.exp_names{1};
            if app.exp_counter > 1
                for i=2:app.exp_counter
                    new_name = [new_name ' + ' app.exp_names{i}];
                end
            end
            
            % to get the pwd where the results_dir is located we use cd
            cd(app.results_dir);
            cd ..;
            % now we concat the pwd with the new results folder name
            new_results_folder = [pwd '\' new_name];
            % to rename the results folder, we just use movefile with the
            % old results_dir and the new_results_folder. that way the
            % folder is renamed and keeps its content.
            movefile(app.results_dir, new_results_folder)
            %% --- reset the app's properties ---
            % let the user know the data analysed has been saved.
            msgbox("Save completed! You may now load new tissues, or close the app.")
            % clear the app's properties so it can be used instantly for a
            % new analysis
            app.exp_counter = 0;
            app.exp_cropped = false;
            cla(app.UIAxes_all);
            legend(app.UIAxes_all, 'hide');
            cla(app.UIAxes_current);
            app.data = {};
            app.raw_data = {};
            app.peri = {};
            app.exp_names = {};
            app.results_dir = "";
            app.results_folder_created = false;
            app.intervals = '';
            app.TextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Plots = uipanel(app.UIFigure);
            app.UIAxes_all = uiaxes(app.Plots);
            title(app.UIAxes_all, 'All Tissues')
            xlabel(app.UIAxes_all, 'Minutes')
            ylabel(app.UIAxes_all, 'Normalized MFI')
            app.UIAxes_current = uiaxes(app.Plots);
            title(app.UIAxes_current, 'Current Tissue')
            ylabel(app.UIAxes_current, 'Normalized MFI')
            app.TextArea = uitextarea(app.Plots);
            app.TextArea.Editable = 'off';
            app.Buttons = uipanel(app.UIFigure);
            app.SaveAnalysisButton = uibutton(app.Buttons, 'push');
            app.SaveAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisButtonPushed, true);
            app.SaveAnalysisButton.Text = 'Save Analysis';
            app.LoadTissueAnalyzePermeabilityButton = uibutton(app.Buttons, 'push');
            app.LoadTissueAnalyzePermeabilityButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExpAndAnalyze, true);
            app.LoadTissueAnalyzePermeabilityButton.Text = {'Load Tissue &'; 'Analyze Permeability'};
            app.AnalyzeMotilityButton = uibutton(app.Buttons, 'push');
            app.AnalyzeMotilityButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeMotility, true);
            app.AnalyzeMotilityButton.Text = 'Analyze Motility';
            app.LoadPreviousAnalysisButton = uibutton(app.Buttons, 'push');
            app.LoadPreviousAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviousAnalysis, true);
            app.LoadPreviousAnalysisButton.Text = 'Load Previous Analysis';
            app.RemoveFrameorTissueButton = uibutton(app.Buttons, 'push');
            app.RemoveFrameorTissueButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFrameOrExp, true);
            app.RemoveFrameorTissueButton.Text = {'Remove Frame'; 'or Tissue'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_exe2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end